classdef AddExpWindow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        NameEditFieldLabel   matlab.ui.control.Label
        NameEditField        matlab.ui.control.EditField
        DateDatePickerLabel  matlab.ui.control.Label
        DateDatePicker       matlab.ui.control.DatePicker
        OKButton             matlab.ui.control.Button
        CancelButton         matlab.ui.control.Button
        ExperEditFieldLabel  matlab.ui.control.Label
        ExperEditField       matlab.ui.control.EditField
    end

    
    properties (Access = private)
        MainWindowApp % Description
        Name
        Date
        Exper
    end
    
    methods (Access = private)
        
        function ObtainAllValues(app)
            app.Name=app.NameEditField.Value;
            app.Date=char(app.DateDatePicker.Value);
            app.Exper=app.ExperEditField.Value;
        end
        
        function PutAllValues(app)
            app.NameEditField.Value=app.Name;
            app.DateDatePicker.Value=datetime(app.Date);
            app.ExperEditField.Value=app.Exper;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.MainWindowApp=mainapp;
            app.ObtainAllValues();
            app.Date=datetime('today');
            app.PutAllValues();
        end

        % Button pushed function: OKButton
        function OKButtonPushedFun(app, event)
            % Obtain new subj info
            app.ObtainAllValues()
            % Create new exp and add to system
            [state,newID]=app.MainWindowApp.SubjSystem.CreateNewExp(app.MainWindowApp.selected_subjID,app.Name,app.Exper,app.Date);
            if state==1
                errordlg('State 1: New exp ID has been existed. Please regenerated again.','Add New Exp Error')
                return
            elseif state==2
                errordlg('State 2: New exp Data Path has been existed. Please check it.','Add New Exp Error')
                return
            elseif state==3
                errordlg('State 3: Selected subject cannot be found. Please check it.','Add New Exp Error')
                return
            end
            % Main window update subj and exp tables
            Selection_and_Regenerate_SubjTable(app.MainWindowApp,app.MainWindowApp.selected_subjID);
            Selection_and_Regenerate_ExpTable(app.MainWindowApp,app.MainWindowApp.selected_expID);
            % close add subj window
            delete(app);
        end

        % Button pushed function: CancelButton
        function CancelButtonPushedFun(app, event)
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.NameEditFieldLabel = uilabel(app.UIFigure);
            app.NameEditFieldLabel.Text = 'Name:';
            app.NameEditField = uieditfield(app.UIFigure, 'text');
            app.DateDatePickerLabel = uilabel(app.UIFigure);
            app.DateDatePickerLabel.Text = 'Date: ';
            app.DateDatePicker = uidatepicker(app.UIFigure);
            app.DateDatePicker.Value = datetime([1991 1 1]);
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushedFun, true);
            app.OKButton.Text = 'OK';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushedFun, true);
            app.CancelButton.Text = 'Cancel';
            app.ExperEditFieldLabel = uilabel(app.UIFigure);
            app.ExperEditFieldLabel.Text = 'Exper: ';
            app.ExperEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AddExpWindow(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end