classdef AddSubjWindow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        NameEditFieldLabel       matlab.ui.control.Label
        NameEditField            matlab.ui.control.EditField
        BirthdayDatePickerLabel  matlab.ui.control.Label
        BirthdayDatePicker       matlab.ui.control.DatePicker
        HandDropDownLabel        matlab.ui.control.Label
        HandDropDown             matlab.ui.control.DropDown
        GenderDropDownLabel      matlab.ui.control.Label
        GenderDropDown           matlab.ui.control.DropDown
        OKButton                 matlab.ui.control.Button
        CancelButton             matlab.ui.control.Button
    end

    
    properties (Access = private)
        MainWindowApp % Description
        Name
        Birthday
        Hand
        Gender
    end
    
    methods (Access = private)
        
        function ObtainAllValues(app)
            app.Name=app.NameEditField.Value;
            app.Birthday=char(app.BirthdayDatePicker.Value);
            app.Hand=app.HandDropDown.Value;
            app.Gender=app.GenderDropDown.Value;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.MainWindowApp=mainapp;
            app.ObtainAllValues();
        end

        % Button pushed function: OKButton
        function OKButtonPushedFun(app, event)
            % Obtain new subj info
            app.ObtainAllValues()
            % Check Name
            if isempty(app.Name)
                errordlg('Subject Name must be given!','Add New Subject Error')
                return
            end
            % Create new subj and add to system
            [state,newID]=app.MainWindowApp.SubjSystem.CreateNewSubj(app.Name,app.Birthday,app.Hand,app.Gender);
            if state==1
                errordlg('State 1: New subject ID has been existed. Please regenerated again.','Add New Subject Error')
                return
            elseif state==2
                errordlg('State 2: New subject Data Path has been existed. Please check it.','Add New Subject Error')
                return
            end
            % Main window update subj and exp tables
            Selection_and_Regenerate_SubjTable(app.MainWindowApp,app.MainWindowApp.selected_subjID);
            Selection_and_Regenerate_ExpTable(app.MainWindowApp,app.MainWindowApp.selected_expID);
            % close add subj window
            delete(app);
        end

        % Button pushed function: CancelButton
        function CancelButtonPushedFun(app, event)
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.NameEditFieldLabel = uilabel(app.UIFigure);
            app.NameEditFieldLabel.Text = 'Name:';
            app.NameEditField = uieditfield(app.UIFigure, 'text');
            app.BirthdayDatePickerLabel = uilabel(app.UIFigure);
            app.BirthdayDatePickerLabel.Text = 'Birthday:';
            app.BirthdayDatePicker = uidatepicker(app.UIFigure);
            app.BirthdayDatePicker.Value = datetime([1991 1 1]);
            app.HandDropDownLabel = uilabel(app.UIFigure);
            app.HandDropDownLabel.Text = 'Hand:';
            app.HandDropDown = uidropdown(app.UIFigure);
            app.HandDropDown.Items = {'Right', 'Left'};
            app.HandDropDown.Value = 'Right';
            app.GenderDropDownLabel = uilabel(app.UIFigure);
            app.GenderDropDownLabel.Text = 'Gender:';
            app.GenderDropDown = uidropdown(app.UIFigure);
            app.GenderDropDown.Items = {'', 'Female', 'Male'};
            app.GenderDropDown.Value = '';
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushedFun, true);
            app.OKButton.Text = 'OK';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushedFun, true);
            app.CancelButton.Text = 'Cancel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AddSubjWindow(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end