classdef ConnectToSourceWindow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        SelectedSignalSourceEditFieldLabel  matlab.ui.control.Label
        SelectedSignalSourceEditField  matlab.ui.control.EditField
        UITable                        matlab.ui.control.Table
        SearchSourceButton             matlab.ui.control.Button
        ConnecttoSourceButton          matlab.ui.control.Button
        CancelButton                   matlab.ui.control.Button
        SimulationSourceGeneratorButton  matlab.ui.control.Button
    end

    
    properties (Access = private)
        MainAppWindow
        SearchResult
        lsllib
        SelectStream
    end
    
    methods (Access = private)
        
        function DispData = GenSourceTableData(app)
            % ID, name, channel number, sampling frequency, channel format
            if isempty(app.SearchResult)
                DispData={};
            else
                DispData=cell(length(app.SearchResult),5);
                for i=1:length(app.SearchResult)
                    DispData{i,1}=app.SearchResult{i}.source_id;
                    DispData{i,2}=app.SearchResult{i}.name;
                    DispData{i,3}=app.SearchResult{i}.channel_count;
                    DispData{i,4}=app.SearchResult{i}.nominal_srate;
                    DispData{i,5}=app.SearchResult{i}.channel_format;
                end
            end
        end
        
        function SearchPossibleInlet(app)
            searchbar=waitbar(0.5,'Please waiting!!');
            app.SearchResult=lsl_resolve_byprop(app.lsllib,'type','EEG');
            delete(searchbar)
            if isempty(app.SearchResult)
                warndlg('Cannot find any signal source.','Warning')
                app.SearchResult=[];
                app.Selection_and_Regenerate_SourceTable([]);
            else
                if isempty(app.SelectStream)
                    app.Selection_and_Regenerate_SourceTable([]);
                else
                    app.Selection_and_Regenerate_SourceTable(app.SelectStream.source_id);
                end
            end
        end
        
        function Selection_and_Nonregenerate_SourceTable(app,SeletRow)
            targetSelectRow=[];
            if ischar(SeletRow)
                tmp=app.UITable.Data;
                for i=1:size(tmp,1)
                    if strcmp(SeletRow,tmp{i,1})
                        targetSelectRow=i;
                        break;
                    end
                end
            elseif isnumeric(SeletRow) && ~isempty(SeletRow)
                SeletRow=floor(SeletRow);
                if SeletRow>=1 && SeletRow<=size(app.UITable.DisplayData,1)
                    targetSelectRow=SeletRow;
                else
                    targetSelectRow=[];
                end
            elseif ~isempty(SeletRow)
                error('Selection_and_Regenerate_SourceTable SelectRow type Wrong')
            end
            if isempty(targetSelectRow)
                app.SelectStream=[];
            else
                app.SelectStream=app.SearchResult{targetSelectRow};
            end
            app.SourceTableDispSelection(targetSelectRow);
            app.UpdateSelectedSourceDispTxt(targetSelectRow);
        end
        
        function Selection_and_Regenerate_SourceTable(app,SeletRow)
            app.UITable.Data=app.GenSourceTableData();
            app.Selection_and_Nonregenerate_SourceTable(SeletRow);
        end
        
        function SourceTableDispSelection(app,SeletRow)
            clean_style=uistyle('BackgroundColor','white');
            select_style=uistyle('BackgroundColor','yellow');
            % Remove all selection
            app.UITable.addStyle(clean_style);
            % Add select style
            if ~isempty(SeletRow)
                app.UITable.addStyle(select_style,'row',SeletRow);
            end
        end
        
        function UpdateSelectedSourceDispTxt(app,targetSelectRow)
            if isempty(app.SelectStream)
                DispTxt='None';
            else
                DispTxt=[app.UITable.DisplayData{targetSelectRow,1} ', ' app.UITable.DisplayData{targetSelectRow,2} ', Ch ' num2str(app.UITable.DisplayData{targetSelectRow,3}) ', ' num2str(app.UITable.DisplayData{targetSelectRow,4}) 'Hz'];
            end
            app.SelectedSignalSourceEditField.Value=DispTxt;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.MainAppWindow=mainapp;
            app.SearchResult=[];
            app.SelectStream=[];
            app.lsllib=lsl_loadlib();
%             app.SearchPossibleInlet();
        end

        % Button pushed function: SimulationSourceGeneratorButton
        function SimulationSourceGeneratorButtonFun(app, event)
            appPath=fileparts(mfilename('fullpath'));
            Path_SimulationSourceGenerator=fullfile(appPath,'SimulationSourceGenerator.mlapp');
            system(['matlab -nodesktop -nosplash -r "run(''' Path_SimulationSourceGenerator ''');" &']);
        end

        % Button pushed function: SearchSourceButton
        function SearchSourceButtonFun(app, event)
            app.SearchSourceButton.Enable=false;
            app.ConnecttoSourceButton.Enable=false;
            app.CancelButton.Enable=false;
            app.UITable.Enable='off';
            app.SearchPossibleInlet();
            app.SearchSourceButton.Enable=true;
            app.ConnecttoSourceButton.Enable=true;
            app.ConnecttoSourceButton.Enable=true;
            app.CancelButton.Enable=true;
            app.UITable.Enable='on';
        end

        % Cell selection callback: UITable
        function SourceTableSelectionFun(app, event)
            indices = event.Indices;
            SeletRow=indices(1,1);
            app.Selection_and_Nonregenerate_SourceTable(SeletRow);
        end

        % Button pushed function: ConnecttoSourceButton
        function ConnecttoSourceButtonFun(app, event)
            if isempty(app.SelectStream)
                errordlg('Please select one source!!')
            else
                delete(app.MainAppWindow.inletstreaminfo)
                app.MainAppWindow.inletstreaminfo=app.SelectStream;
%                 app.MainAppWindow.inlet=lsl_inlet(app.SelectStream);
                app.MainAppWindow.DispInletInfo();
                delete(app)
            end
            
        end

        % Button pushed function: CancelButton
        function CancelButtonFun(app, event)
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SelectedSignalSourceEditFieldLabel = uilabel(app.UIFigure);
            app.SelectedSignalSourceEditFieldLabel.Text = 'Selected Signal Source: ';
            app.SelectedSignalSourceEditField = uieditfield(app.UIFigure, 'text');
            app.SelectedSignalSourceEditField.Editable = 'off';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Source ID'; 'Name'; 'Channel Num'; 'Sampling Freq (Hz)'; 'Channel Format'};
            app.UITable.RowName = {};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @SourceTableSelectionFun, true);
            app.SearchSourceButton = uibutton(app.UIFigure, 'push');
            app.SearchSourceButton.ButtonPushedFcn = createCallbackFcn(app, @SearchSourceButtonFun, true);
            app.SearchSourceButton.Text = 'Search Source';
            app.ConnecttoSourceButton = uibutton(app.UIFigure, 'push');
            app.ConnecttoSourceButton.ButtonPushedFcn = createCallbackFcn(app, @ConnecttoSourceButtonFun, true);
            app.ConnecttoSourceButton.Text = 'Connect to Source';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonFun, true);
            app.CancelButton.Text = 'Cancel';
            app.SimulationSourceGeneratorButton = uibutton(app.UIFigure, 'push');
            app.SimulationSourceGeneratorButton.ButtonPushedFcn = createCallbackFcn(app, @SimulationSourceGeneratorButtonFun, true);
            app.SimulationSourceGeneratorButton.Text = 'Simulation Source Generator';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ConnectToSourceWindow(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end