classdef DataSystemConfigure < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        DataPathLabel            matlab.ui.control.Label
        DataPathDisp             matlab.ui.control.Label
        DatePathSelectionButton  matlab.ui.control.Button
        OKButton                 matlab.ui.control.Button
        CancelButton             matlab.ui.control.Button
    end

    
    properties (Access = private)
        MainWindowApp
        ConfigFile
        ConfigContent
    end
    
    methods (Access = private)
        
        function ReadConfig(app)
            % Read configurations
            fid=fopen(app.ConfigFile,'r');
            tmp=textscan(fid,'%s%[^\r]');
            fclose(fid);
            % Store configurations in app
            ConfigTable=cell(length(tmp{1}),2);
            for i=1:length(tmp{1})
                ConfigTable{i,1}=tmp{1}{i};
                ConfigTable{i,2}=tmp{2}{i};
            end
            app.ConfigContent=ConfigTable;
        end
        
        function DispConfig(app)
            % Disp Data Path
            Index=find(contains(app.ConfigContent(:,1),'DataPath'));
            tmp=app.ConfigContent{Index,2};
            app.DataPathDisp.Text=tmp;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Mainapp)
            app.MainWindowApp=Mainapp;
            app.ConfigFile=Mainapp.ConfigFile;
            app.ReadConfig();
            app.DispConfig();
        end

        % Button pushed function: DatePathSelectionButton
        function DataPathSelectionButtonFun(app, event)
            appPath=fileparts(mfilename('fullpath'));
            selpath=uigetdir(appPath,'Data Path');
            if ischar(selpath)
                Index=find(contains(app.ConfigContent(:,1),'DataPath'));
                app.ConfigContent{Index,2}=selpath;
                app.DispConfig();
            end
        end

        % Button pushed function: CancelButton
        function CancelButtonFun(app, event)
            delete(app)
        end

        % Button pushed function: OKButton
        function OKButtonFun(app, event)
            writecell(app.ConfigContent,app.ConfigFile,'Delimiter','space','QuoteStrings',false);
            app.MainWindowApp.SubjSystem=SubjMangSystem(app.ConfigFile);
            Selection_and_Regenerate_SubjTable(app.MainWindowApp,[]);
            Selection_and_Regenerate_ExpTable(app.MainWindowApp,[]);
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DataPathLabel = uilabel(app.UIFigure);
            app.DataPathLabel.Text = 'Data Path:';
            app.DataPathDisp = uilabel(app.UIFigure);
            app.DataPathDisp.Text = '';
            app.DatePathSelectionButton = uibutton(app.UIFigure, 'push');
            app.DatePathSelectionButton.ButtonPushedFcn = createCallbackFcn(app, @DataPathSelectionButtonFun, true);
            app.DatePathSelectionButton.Text = 'Select Folder';
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonFun, true);
            app.OKButton.Text = 'OK';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonFun, true);
            app.CancelButton.Text = 'Cancel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DataSystemConfigure(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end