classdef FeedbackInterfaceDesign < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        UITable                         matlab.ui.control.Table
        StepParameterPanel              matlab.ui.container.Panel
        AddStep                         matlab.ui.control.Button
        RemoveStep                      matlab.ui.control.Button
        StepTypeDropDownLabel           matlab.ui.control.Label
        StepTypeDropDown                matlab.ui.control.DropDown
        DurationsEditFieldLabel         matlab.ui.control.Label
        DurationsEditField              matlab.ui.control.NumericEditField
        FeedbackThresholdEditFieldLabel  matlab.ui.control.Label
        FeedbackThresholdEditField      matlab.ui.control.NumericEditField
        ChannelDropDownLabel            matlab.ui.control.Label
        ChannelDropDown                 matlab.ui.control.DropDown
        FeedbackFrequencyRangeHzEditFieldLabel  matlab.ui.control.Label
        FeedbackFrequencyRangeHzEditField  matlab.ui.control.NumericEditField
        Label                           matlab.ui.control.Label
        FeedbackFrequencyRangeHzEditField_2  matlab.ui.control.NumericEditField
        FeedbackDirectionDropDownLabel  matlab.ui.control.Label
        FeedbackDirectionDropDown       matlab.ui.control.DropDown
        UpStep                          matlab.ui.control.Button
        DownStep                        matlab.ui.control.Button
        SubjectEditFieldLabel           matlab.ui.control.Label
        SubjectEditField                matlab.ui.control.EditField
        ExperimentEditFieldLabel        matlab.ui.control.Label
        ExperimentEditField             matlab.ui.control.EditField
        DataPathEditFieldLabel          matlab.ui.control.Label
        DataPathEditField               matlab.ui.control.EditField
        SaveButton                      matlab.ui.control.Button
        CancelButton                    matlab.ui.control.Button
    end

    
    properties (Access = private)
        MainApp
        
        ExpDataPath
        
        InterfaceContents
        
        SelectRow
    end
    
    methods (Access = private)
        function UpdateSelectedSubjDispTxt(app)
            if isempty(app.MainApp.selected_subjID)
                DispTxt='None';
            else
                SubjList=app.MainApp.SubjSystem.SubjList;
                SelectSubjInd=app.MainApp.SubjSystem.searchSubj(app.MainApp.selected_subjID);
                if isempty(SelectSubjInd)
                    DispTxt='None';
                else
                    SubjInfo=SubjList(SelectSubjInd);
                    DispTxt=[SubjInfo.ID ', ' SubjInfo.Name];
                end
            end
            app.SubjectEditField.Value=DispTxt;
        end
        
        function UpdateSelectedExpDispTxt(app)
            if isempty(app.MainApp.selected_expID)
                DispTxt='None';
            else
                SubjList=app.MainApp.SubjSystem.SubjList;
                SelectSubjInd=app.MainApp.SubjSystem.searchSubj(app.MainApp.selected_subjID);
                if isempty(SelectSubjInd)
                    DispTxt='None';
                else
                    SubjInfo=SubjList(SelectSubjInd);
                    ExpList=SubjInfo.ExpList;
                    SelectExpInd=SubjInfo.searchExp(app.MainApp.selected_expID);
                    if isempty(SelectExpInd)
                        DispTxt='None';
                    else
                        ExpInfo=ExpList(SelectExpInd);
                        DispTxt=[ExpInfo.ID ', ' ExpInfo.Name ', ' ExpInfo.Date];
                    end
                end
            end
            app.ExperimentEditField.Value=DispTxt;
            
            app.DataPathEditField.Value=app.ExpDataPath;
        end
        
        function UpdateEditFieldEnable(app)
            if strcmp(app.StepTypeDropDown.Value,app.StepTypeDropDown.Items{end})
                EnableValue='on';
            else
                EnableValue='off';
            end
            app.FeedbackThresholdEditField.Enable=EnableValue;
            app.ChannelDropDown.Enable=EnableValue;
            app.FeedbackFrequencyRangeHzEditField.Enable=EnableValue;
            app.FeedbackFrequencyRangeHzEditField_2.Enable=EnableValue;
            app.FeedbackDirectionDropDown.Enable=EnableValue;
        end
        
        function UpdateChannelSelection(app)
            while 1
                answer = inputdlg('How many channels will you use?','Total Channel Number');
                if isempty(answer)
                    delete(app)
                    return;
                end
                answer=floor(str2num(answer{1}));
                if numel(answer)~=1
                    waitfor(warndlg('Please input one number'))
                    continue;
                end
                if answer<1
                    waitfor(warndlg('The total channel number must be larger than or equal to 1'))
                    continue;
                end
                break;
            end
            Contents={};
            for i=1:answer
                Contents{i}=num2str(i);
            end
            app.ChannelDropDown.Items=Contents;
            app.ChannelDropDown.Value=Contents{1};
        end
        
        function UpdateAllTable(app)
            for i=2:size(app.InterfaceContents,1)
                app.InterfaceContents{i,1}=num2str(i-1);
            end
            app.UITable.Data=app.InterfaceContents(2:end,:);
            if app.SelectRow<0
                app.SelectRow=0;
            end
            if app.SelectRow>=size(app.InterfaceContents,1)
                app.SelectRow=size(app.InterfaceContents,1)-1;
            end
            app.UpdateTableSelection();
        end
        
        function UpdateTableSelection(app)
            clean_style=uistyle('BackgroundColor','white');
            select_style=uistyle('BackgroundColor','yellow');
            % Clean Table
            app.UITable.addStyle(clean_style);
            % Draw selection
            if app.SelectRow==0
                return
            else
                app.UITable.addStyle(select_style,'row',app.SelectRow);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.MainApp=mainapp;
            ExpInfo=app.MainApp.SubjSystem.ObtainExpInfo(app.MainApp.selected_subjID,app.MainApp.selected_expID);
            app.ExpDataPath=ExpInfo.DataPath;
            InterfaceParameterFile=fullfile(app.ExpDataPath,'InterfaceParameters.txt');
            if exist(InterfaceParameterFile,'file')
                fid=fopen(InterfaceParameterFile,'r');
                C=textscan(fid,'%[^\n]');
                fclose(fid);
                C=C{1};
                app.InterfaceContents={};
                for i=1:length(C)
                    tmp=split(C{i},',');
                    for j=1:length(tmp)
                        app.InterfaceContents{i,j}=tmp{j};
                    end
                end
            else
                app.InterfaceContents={'Order','StepType','Duration','FeedbackThreshold','Channel','FeedbackFreq','FeedbackDirection'};
            end
            app.SelectRow=0;
            
            app.UpdateSelectedSubjDispTxt();
            app.UpdateSelectedExpDispTxt();
            
            app.UpdateAllTable();
            
            app.UpdateEditFieldEnable();
            app.UpdateChannelSelection();
        end

        % Button pushed function: CancelButton
        function CancelButtonFun(app, event)
            delete(app);
        end

        % Value changed function: StepTypeDropDown
        function StepTypeChangeFun(app, event)
            app.UpdateEditFieldEnable();
        end

        % Button pushed function: AddStep
        function AddStepFun(app, event)
%             Add_row=max(app.SelectRow+2,size(app.InterfaceContents,1)+1);
            if app.SelectRow==0
                Add_row=size(app.InterfaceContents,1)+1;
            else
                Add_row=app.SelectRow+2;
            end
            if Add_row<=size(app.InterfaceContents,1)
                app.InterfaceContents(Add_row+1:end+1,:)=app.InterfaceContents(Add_row:end,:);
            end
            switch app.StepTypeDropDown.Value
                case app.StepTypeDropDown.Items{1}
%                     app.InterfaceContents{Add_row,1}=num2str(Add_row-1);
                    app.InterfaceContents{Add_row,2}=app.StepTypeDropDown.Value;
                    app.InterfaceContents{Add_row,3}=num2str(app.DurationsEditField.Value);
                    app.InterfaceContents{Add_row,4}='';
                    app.InterfaceContents{Add_row,5}='';
                    app.InterfaceContents{Add_row,6}='';
                    app.InterfaceContents{Add_row,7}='';
                case app.StepTypeDropDown.Items{2}
%                     app.InterfaceContents{Add_row,1}=num2str(Add_row-1);
                    app.InterfaceContents{Add_row,2}=app.StepTypeDropDown.Value;
                    app.InterfaceContents{Add_row,3}=num2str(app.DurationsEditField.Value);
                    app.InterfaceContents{Add_row,4}='';
                    app.InterfaceContents{Add_row,5}='';
                    app.InterfaceContents{Add_row,6}='';
                    app.InterfaceContents{Add_row,7}='';
                case app.StepTypeDropDown.Items{3}
%                     app.InterfaceContents{Add_row,1}=num2str(Add_row-1);
                    if app.FeedbackFrequencyRangeHzEditField.Value>app.FeedbackFrequencyRangeHzEditField_2.Value
                        warndlg('Please check feedback frequency range')
                        app.InterfaceContents(Add_row,:)=[];
                        Add_row=Add_row-2;
                    else
                        app.InterfaceContents{Add_row,2}=app.StepTypeDropDown.Value;
                        app.InterfaceContents{Add_row,3}=num2str(app.DurationsEditField.Value);
                        app.InterfaceContents{Add_row,4}=num2str(app.FeedbackThresholdEditField.Value);
                        app.InterfaceContents{Add_row,5}=app.ChannelDropDown.Value;
                        app.InterfaceContents{Add_row,6}=[num2str(app.FeedbackFrequencyRangeHzEditField.Value) ' ~ ' num2str(app.FeedbackFrequencyRangeHzEditField_2.Value)];
                        app.InterfaceContents{Add_row,7}=app.FeedbackDirectionDropDown.Value;
                    end
            end
            app.SelectRow=Add_row;
            app.UpdateAllTable();
        end

        % Button pushed function: RemoveStep
        function RemoveStepFun(app, event)
            if app.SelectRow==0
                return;
            end
            remove_row=app.SelectRow+1;
            app.InterfaceContents(remove_row,:)=[];
            app.SelectRow=app.SelectRow-1;
            app.UpdateAllTable();
        end

        % Cell selection callback: UITable
        function UITableSelectFun(app, event)
            indices = event.Indices;
            app.SelectRow=indices(1,1);
            app.UpdateTableSelection();
        end

        % Button pushed function: UpStep
        function UpFun(app, event)
            if app.SelectRow==0 || app.SelectRow==1
                return;
            end
            current_row=app.SelectRow+1;
            tmp=app.InterfaceContents(current_row,:);
            app.InterfaceContents(current_row,:)=[];
            Add_row=current_row-1;
            app.InterfaceContents(Add_row+1:end+1,:)=app.InterfaceContents(Add_row:end,:);
            app.InterfaceContents(Add_row,:)=tmp;
            app.SelectRow=Add_row-1;
            app.UpdateAllTable();
        end

        % Button pushed function: DownStep
        function DownFun(app, event)
            if app.SelectRow==0 || app.SelectRow==size(app.InterfaceContents,1)-1
                return;
            end
            current_row=app.SelectRow+1;
            tmp=app.InterfaceContents(current_row,:);
            Add_row=current_row+2;
            app.InterfaceContents(Add_row+1:end+1,:)=app.InterfaceContents(Add_row:end,:);
            app.InterfaceContents(Add_row,:)=tmp;
            app.InterfaceContents(current_row,:)=[];
            app.SelectRow=current_row;
            app.UpdateAllTable();
        end

        % Button pushed function: SaveButton
        function SaveFun(app, event)
            InterfaceParameterFile=fullfile(app.ExpDataPath,'InterfaceParameters.txt');
            if exist(InterfaceParameterFile,'file')
                delete(InterfaceParameterFile)
            end
            fid=fopen(InterfaceParameterFile,'a');
            for i=1:size(app.InterfaceContents,1)
                fprintf(fid,'%s,%s,%s,%s,%s,%s,%s\n',app.InterfaceContents{i,1},...
                                                     app.InterfaceContents{i,2},...
                                                     app.InterfaceContents{i,3},...
                                                     app.InterfaceContents{i,4},...
                                                     app.InterfaceContents{i,5},...
                                                     app.InterfaceContents{i,6},...
                                                     app.InterfaceContents{i,7});
            end
            fclose(fid);
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Order'; 'Step Type'; 'Duration (s)'; 'Feedback Threshold'; 'Channel'; 'Feedback Frequency (Hz)'; 'Feedback Direction'};
            app.UITable.RowName = {};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableSelectFun, true);
            app.StepParameterPanel = uipanel(app.UIFigure);
            app.StepParameterPanel.Title = 'Step Parameter';
            app.AddStep = uibutton(app.StepParameterPanel, 'push');
            app.AddStep.ButtonPushedFcn = createCallbackFcn(app, @AddStepFun, true);
            app.AddStep.Text = '+';
            app.RemoveStep = uibutton(app.StepParameterPanel, 'push');
            app.RemoveStep.ButtonPushedFcn = createCallbackFcn(app, @RemoveStepFun, true);
            app.RemoveStep.Text = '-';
            app.StepTypeDropDownLabel = uilabel(app.StepParameterPanel);
            app.StepTypeDropDownLabel.Text = 'Step Type:';
            app.StepTypeDropDown = uidropdown(app.StepParameterPanel);
            app.StepTypeDropDown.Items = {'Open Eyes', 'Close Eyes', 'NF'};
            app.StepTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @StepTypeChangeFun, true);
            app.StepTypeDropDown.Value = 'Open Eyes';
            app.DurationsEditFieldLabel = uilabel(app.StepParameterPanel);
            app.DurationsEditFieldLabel.Text = 'Duration (s): ';
            app.DurationsEditField = uieditfield(app.StepParameterPanel, 'numeric');
            app.DurationsEditField.Limits = [1 Inf];
            app.DurationsEditField.Value = 1;
            app.FeedbackThresholdEditFieldLabel = uilabel(app.StepParameterPanel);
            app.FeedbackThresholdEditFieldLabel.Text = 'Feedback Threshold:';
            app.FeedbackThresholdEditField = uieditfield(app.StepParameterPanel, 'numeric');
            app.FeedbackThresholdEditField.Limits = [0 Inf];
            app.ChannelDropDownLabel = uilabel(app.StepParameterPanel);
            app.ChannelDropDownLabel.Text = 'Channel: ';
            app.ChannelDropDown = uidropdown(app.StepParameterPanel);
            app.ChannelDropDown.Items = {'1'};
            app.ChannelDropDown.Value = '1';
            app.FeedbackFrequencyRangeHzEditFieldLabel = uilabel(app.StepParameterPanel);
            app.FeedbackFrequencyRangeHzEditFieldLabel.Text = 'Feedback Frequency Range (Hz):';
            app.FeedbackFrequencyRangeHzEditField = uieditfield(app.StepParameterPanel, 'numeric');
            app.FeedbackFrequencyRangeHzEditField.Limits = [4 30];
            app.FeedbackFrequencyRangeHzEditField.Value = 4;
            app.Label = uilabel(app.StepParameterPanel);
            app.Label.Text = '~';
            app.FeedbackFrequencyRangeHzEditField_2 = uieditfield(app.StepParameterPanel, 'numeric');
            app.FeedbackFrequencyRangeHzEditField_2.Limits = [4 30];
            app.FeedbackFrequencyRangeHzEditField_2.Value = 8;
            app.FeedbackDirectionDropDownLabel = uilabel(app.StepParameterPanel);
            app.FeedbackDirectionDropDownLabel.Text = 'Feedback Direction';
            app.FeedbackDirectionDropDown = uidropdown(app.StepParameterPanel);
            app.FeedbackDirectionDropDown.Items = {'Positive', 'Negative'};
            app.FeedbackDirectionDropDown.Value = 'Positive';
            app.UpStep = uibutton(app.StepParameterPanel, 'push');
            app.UpStep.ButtonPushedFcn = createCallbackFcn(app, @UpFun, true);
            app.UpStep.Text = 'Up';
            app.DownStep = uibutton(app.StepParameterPanel, 'push');
            app.DownStep.ButtonPushedFcn = createCallbackFcn(app, @DownFun, true);
            app.DownStep.Text = 'Down';
            app.SubjectEditFieldLabel = uilabel(app.UIFigure);
            app.SubjectEditFieldLabel.Text = 'Subject: ';
            app.SubjectEditField = uieditfield(app.UIFigure, 'text');
            app.SubjectEditField.Editable = 'off';
            app.ExperimentEditFieldLabel = uilabel(app.UIFigure);
            app.ExperimentEditFieldLabel.Text = 'Experiment: ';
            app.ExperimentEditField = uieditfield(app.UIFigure, 'text');
            app.ExperimentEditField.Editable = 'off';
            app.DataPathEditFieldLabel = uilabel(app.UIFigure);
            app.DataPathEditFieldLabel.Text = 'Data Path: ';
            app.DataPathEditField = uieditfield(app.UIFigure, 'text');
            app.DataPathEditField.Editable = 'off';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveFun, true);
            app.SaveButton.Text = 'Save';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonFun, true);
            app.CancelButton.Text = 'Cancel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FeedbackInterfaceDesign(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end