classdef MainWindow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        DataManagementSystemMenu       matlab.ui.container.Menu
        ConfigurationMenu              matlab.ui.container.Menu
        RebuildDatabaseMenu            matlab.ui.container.Menu
        DevicesMenu                    matlab.ui.container.Menu
        ConnecttoDeviceMenu            matlab.ui.container.Menu
        SubjTable                      matlab.ui.control.Table
        SelectedSubjectLabel           matlab.ui.control.Label
        SelectedSubjectDisp            matlab.ui.control.Label
        ExpTable                       matlab.ui.control.Table
        SelectedExpLabel               matlab.ui.control.Label
        SelectedExpDisp                matlab.ui.control.Label
        SubjTableRefreshButton         matlab.ui.control.Button
        ExpTableRefreshButton          matlab.ui.control.Button
        AddSubjectButton               matlab.ui.control.Button
        RemoveSubjectButton            matlab.ui.control.Button
        EditSubjectButton              matlab.ui.control.Button
        AddExpButton                   matlab.ui.control.Button
        RemoveExpButton                matlab.ui.control.Button
        EditExpButton                  matlab.ui.control.Button
        OpenSubjectDataFolderButton    matlab.ui.control.Button
        OpenExpDataFolderButton        matlab.ui.control.Button
        ConnecttoDeviceButton          matlab.ui.control.Button
        SelectedSourceEditFieldLabel   matlab.ui.control.Label
        SelectedSourceEditField        matlab.ui.control.EditField
        OpenSignalRecordingButton      matlab.ui.control.Button
        OpenFeedbackInterfaceButton    matlab.ui.control.Button
        DesignFeedbackInterfaceButton  matlab.ui.control.Button
    end

    
    properties (Access = public)
        ConfigFile
        SubjSystem
        
        selected_subjID='';
        selected_expID='';
        
%         TestValue=0;
        
        inletstreaminfo
%         inlet
    end
    
    methods (Access = public)
        
        function DispData = GenSubjTableData(app)
            SubjList=app.SubjSystem.SubjList;
            % ID,Name,Gender,Hand,Birthday
            DispData=cell(length(SubjList),5);
            for i=1:length(SubjList)
                DispData{i,1}=SubjList(i).ID;
                DispData{i,2}=SubjList(i).Name;
                DispData{i,3}=SubjList(i).Gender;
                DispData{i,4}=SubjList(i).Hand;
                DispData{i,5}=SubjList(i).Birthday;
            end
        end
        
        function DispData=GenExpTableData(app)
            SubjInd=app.SubjSystem.searchSubj(app.selected_subjID);
            if isempty(SubjInd)
                DispData=[];
            else
                SubjList=app.SubjSystem.SubjList;
                SubjInfo=SubjList(SubjInd);
                ExpList=SubjInfo.ExpList;
                % ID,Name,Date,Exper
                DispData=cell(length(ExpList),4);
                for i=1:length(ExpList)
                    DispData{i,1}=ExpList(i).ID;
                    DispData{i,2}=ExpList(i).Name;
                    DispData{i,3}=ExpList(i).Date;
                    DispData{i,4}=ExpList(i).Exper;
                end
            end
        end
        
        function SubjTableDispSelection(app,SeletRow)
            clean_style=uistyle('BackgroundColor','white');
            select_style=uistyle('BackgroundColor','yellow');
            % Remove all selection
            app.SubjTable.addStyle(clean_style);
            % Add select style
            if ~isempty(SeletRow)
                app.SubjTable.addStyle(select_style,'row',SeletRow);
            end
        end
        
        function ExpTableDispSelection(app,SeletRow)
            clean_style=uistyle('BackgroundColor','white');
            select_style=uistyle('BackgroundColor','yellow');
            % Remove all selection
            app.ExpTable.addStyle(clean_style);
            % Add select style
            if ~isempty(SeletRow)
                app.ExpTable.addStyle(select_style,'row',SeletRow);
            end
        end
        
        function UpdateSelectedSubjDispTxt(app)
            if isempty(app.selected_subjID)
                DispTxt='None';
            else
                SubjList=app.SubjSystem.SubjList;
                SelectSubjInd=app.SubjSystem.searchSubj(app.selected_subjID);
                if isempty(SelectSubjInd)
                    DispTxt='None';
                else
                    SubjInfo=SubjList(SelectSubjInd);
                    DispTxt=[SubjInfo.ID ', ' SubjInfo.Name];
                end
            end
            app.SelectedSubjectDisp.Text=DispTxt;
        end
        
        function UpdateSelectedExpDispTxt(app)
            if isempty(app.selected_expID)
                DispTxt='None';
            else
                SubjList=app.SubjSystem.SubjList;
                SelectSubjInd=app.SubjSystem.searchSubj(app.selected_subjID);
                if isempty(SelectSubjInd)
                    DispTxt='None';
                else
                    SubjInfo=SubjList(SelectSubjInd);
                    ExpList=SubjInfo.ExpList;
                    SelectExpInd=SubjInfo.searchExp(app.selected_expID);
                    if isempty(SelectExpInd)
                        DispTxt='None';
                    else
                        ExpInfo=ExpList(SelectExpInd);
                        DispTxt=[ExpInfo.ID ', ' ExpInfo.Name ', ' ExpInfo.Date];
                    end
                end
            end
            app.SelectedExpDisp.Text=DispTxt;
        end
        
        function Selection_and_Nonregenerate_SubjTable(app,SeletRow)
            targetSelectRow=[];
            if ischar(SeletRow)
                tmp=app.SubjTable.DisplayData;
                for i=1:size(tmp,1)
                    if strcmp(SeletRow,tmp{i,1})
                        targetSelectRow=i;
                        break;
                    end
                end
            elseif isnumeric(SeletRow) && ~isempty(SeletRow)
                SeletRow=floor(SeletRow);
                if SeletRow>=1 && SeletRow<=size(app.SubjTable.DisplayData,1)
                    targetSelectRow=SeletRow;
                else
                    targetSelectRow=[];
                end
            elseif ~isempty(SeletRow)
                error('Selection_and_Regenerate_SubjTable SelectRow type Wrong')
            end
            if isempty(targetSelectRow)
                app.selected_subjID='';
            else
                app.selected_subjID=app.SubjTable.DisplayData{targetSelectRow,1};
            end
            app.SubjTableDispSelection(targetSelectRow);
            app.UpdateSelectedSubjDispTxt();
        end
        
        function Selection_and_Regenerate_SubjTable(app,SeletRow)
            app.SubjTable.Data=app.GenSubjTableData();
            app.Selection_and_Nonregenerate_SubjTable(SeletRow);
        end
        
        function Selection_and_Nonregenerate_ExpTable(app,SeletRow)
            targetSelectRow=[];
            if ischar(SeletRow)
                tmp=app.ExpTable.DisplayData;
                for i=1:size(tmp,1)
                    if strcmp(SeletRow,tmp{i,1})
                        targetSelectRow=i;
                        break;
                    end
                end
            elseif isnumeric(SeletRow) && ~isempty(SeletRow)
                if SeletRow>=1 && SeletRow<=size(app.ExpTable.DisplayData,1)
                    targetSelectRow=SeletRow;
                else
                    targetSelectRow=[];
                end
            elseif ~isempty(SeletRow)
                error('Selection_and_Regenerate_SubjTable SelectRow type Wrong')
            end
            if isempty(targetSelectRow)
                app.selected_expID='';
            else
                app.selected_expID=app.ExpTable.DisplayData{targetSelectRow,1};
            end
            app.ExpTableDispSelection(targetSelectRow);
            app.UpdateSelectedExpDispTxt();
        end
        
        function Selection_and_Regenerate_ExpTable(app,SeletRow)
            app.ExpTable.Data=app.GenExpTableData();
            app.Selection_and_Nonregenerate_ExpTable(SeletRow);
        end
        
        function DispInletInfo(app)
            if isempty(app.inletstreaminfo)
                app.SelectedSourceEditField.Value='None';
            else
                app.ConnecttoDeviceButton.Enable=false;
                app.ConnecttoDeviceMenu.Enable=false;
                app.SelectedSourceEditField.Value=[app.inletstreaminfo.source_id ', ' app.inletstreaminfo.name ', Ch ' num2str(app.inletstreaminfo.channel_count) ', ' num2str(app.inletstreaminfo.nominal_srate) 'Hz'];
                app.ConnecttoDeviceButton.Enable=true;
                app.ConnecttoDeviceMenu.Enable=true;
            end
%             if isempty(app.inlet)
%                 app.SelectedSourceEditField.Value='None';
%             else
%                 app.ConnecttoDeviceButton.Enable=false;
%                 app.ConnecttoDeviceMenu.Enable=false;
%                 app.SelectedSourceEditField.Value='Waiting for updating source information';
%                 try
%                     SelectedStream=app.inlet.info(1);
%                     app.SelectedSourceEditField.Value=[SelectedStream.source_id ', ' SelectedStream.name ', Ch ' num2str(SelectedStream.channel_count) ', ' num2str(SelectedStream.nominal_srate) 'Hz'];
%                 catch
%                     warndlg('Source disconnected!!')
%                     delete(app.inlet)
%                     app.inlet=[];
%                     app.SelectedSourceEditField.Value='None';
%                 end
%                 app.ConnecttoDeviceButton.Enable=true;
%                 app.ConnecttoDeviceMenu.Enable=true;
%             end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function MainWindowStartup(app)
%             openWaitBar=waitbar(0,'Open Application');
%             Total_process=4;
%             current_process=0;
%             app.inlet=[];
            app.inletstreaminfo=[];
            % Create Subj Management System
            appPath=fileparts(mfilename('fullpath'));
            app.ConfigFile=fullfile(appPath,'SubjMangSystemConfig.txt');
            app.SubjSystem=SubjMangSystem(app.ConfigFile);
%             current_process=current_process+1;
%             waitbar(current_process/Total_process,openWaitBar);
            % Disp all Subj
            app.Selection_and_Regenerate_SubjTable([]);
%             current_process=current_process+1;
%             waitbar(current_process/Total_process,openWaitBar);
            % Disp all Exp
            app.Selection_and_Regenerate_ExpTable([]);
%             current_process=current_process+1;
%             waitbar(current_process/Total_process,openWaitBar);
            %
%             pause(3)
%             current_process=current_process+1;
%             waitbar(current_process/Total_process,openWaitBar);
%             delete(openWaitBar)
            app.DispInletInfo();
        end

        % Cell selection callback: SubjTable
        function SubjTableSelection(app, event)
            % Obtain select row
            indices = event.Indices;
            SeletRow=indices(1,1);
            % Update subj table
            app.Selection_and_Nonregenerate_SubjTable(SeletRow);
            % Update exp table
            app.Selection_and_Regenerate_ExpTable(app.selected_expID);
        end

        % Cell selection callback: ExpTable
        function ExpTableSelection(app, event)
            % Obtain select row
            indices = event.Indices;
            SeletRow=indices(1,1);
            % Update subj table
%             app.Selection_and_Regenerate_SubjTable(app.selected_subjID);
            % Update exp table
            app.Selection_and_Nonregenerate_ExpTable(SeletRow);
        end

        % Button pushed function: SubjTableRefreshButton
        function SubjRefreshButtonPush(app, event)
            % Disp all Subj
            app.Selection_and_Regenerate_SubjTable(app.selected_subjID);
            % Disp all Exp
            app.Selection_and_Regenerate_ExpTable(app.selected_expID);
        end

        % Button pushed function: ExpTableRefreshButton
        function ExpRefreshButtonPush(app, event)
            % Update subj table
            app.Selection_and_Regenerate_SubjTable(app.selected_subjID);
            % Disp all Exp
            app.Selection_and_Regenerate_ExpTable(app.selected_expID);
        end

        % Button pushed function: AddSubjectButton
        function AddSubjButton(app, event)
            AddSubjWindow(app);
        end

        % Button pushed function: RemoveSubjectButton
        function RemoveSubjButtonFun(app, event)
            % Check whether select subj
            if isempty(app.selected_subjID)
                errordlg('Please select one subject','Delete Subject Error')
                return
            end
            % Ask whether delete data folder
            delet_folder_ques=questdlg('Do you want to delete subject data folder?',...
                                       'Delete Data Folder??',...
                                       'Yes','No','Cancel','Cancel');
            switch delet_folder_ques
                case 'Yes'
                    delet_folder=1;
                case 'No'
                    delet_folder=0;
                otherwise
                    delet_folder=-1;
            end
            % delete subj
            if delet_folder>=0
                state=app.SubjSystem.DeletSubj(app.selected_subjID,delet_folder);
                if state==1
                    errordlg('No selected subject. Please refresh the subject list','Delete Subject Error')
                    return
                elseif state==2
                    delet_ques=questdlg('Subject data folder cannot be found. Do you want to delete this subject?',...
                                                'Delete Subject Error',...
                                                'Yes','No','Cancel','Cancel');
                    switch delet_ques
                        case 'Yes'
                            state=app.SubjSystem.DeletSubj(app.selected_subjID,0);
                        otherwise
                            return
                    end
                end
            else
                errordlg('The delete process is canceled','Delete Subject Error')
                return
            end
            % Update Tables
            app.Selection_and_Regenerate_SubjTable(app.selected_subjID);
            app.Selection_and_Regenerate_ExpTable(app.selected_expID);
        end

        % Button pushed function: EditSubjectButton
        function EditSubjButtonFun(app, event)
            % Check whether select subj
            if isempty(app.selected_subjID)
                errordlg('Please select one subject','Edit Subject Error')
                return
            end
            % Edit Subject
            EditSubjWindow(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Protect Subj System
            app.SubjSystem.SaveObjList();
            % close inlet
            delete(app.inletstreaminfo)
            app.inletstreaminfo=[];
            % Close app
            delete(app)
        end

        % Button pushed function: OpenSubjectDataFolderButton
        function OpenSubjDataFolderButtonFun(app, event)
            % Check whether select subj
            if isempty(app.selected_subjID)
                errordlg('Please select one subject','Open Subject Error')
                return
            end
            % Open folder
            SubjInd=app.SubjSystem.searchSubj(app.selected_subjID);
            SubjInfo=app.SubjSystem.SubjList(SubjInd);
            DataPath=SubjInfo.DataPath;
            if exist(DataPath)
                winopen(DataPath)
            else
                errordlg('Data folder cannot be found. Please check it.','Open Subject Error')
            end
        end

        % Button pushed function: AddExpButton
        function AddExpButtonFun(app, event)
            % Check whether select subj
            if isempty(app.selected_subjID)
                errordlg('Please select one subject','Add New Exp Error')
                return
            end
            % Add new exp
            AddExpWindow(app)
        end

        % Button pushed function: RemoveExpButton
        function RemoveExpButtonFun(app, event)
            % Check whether select exp
            if isempty(app.selected_expID)
                errordlg('Please select one experiment','Delete Experiment Error')
                return
            end
            % Ask whether delete data folder
            delet_folder_ques=questdlg('Do you want to delete experiment data folder?',...
                                       'Delete Data Folder??',...
                                       'Yes','No','Cancel','Cancel');
            switch delet_folder_ques
                case 'Yes'
                    delet_folder=1;
                case 'No'
                    delet_folder=0;
                otherwise
                    delet_folder=-1;
            end
            % delete exp
            if delet_folder>=0
                state=app.SubjSystem.DeletExp(app.selected_subjID,app.selected_expID,delet_folder);
                switch state
                    case 1
                        errordlg('No selected experiment. Please refresh the experiment list','Delete Experiment Error')
                        return
                    case 2
                        delet_ques=questdlg('Experiment data folder cannot be found. Do you want to delete this experiment?',...
                                                    'Delete Experiment Error',...
                                                    'Yes','No','Cancel','Cancel');
                        switch delet_ques
                            case 'Yes'
                                state=app.SubjSystem.DeletExp(app.selected_subjID,app.selected_expID,0);
                            otherwise
                                return
                        end
                    case 3
                        errordlg('No selected subject. Please refresh the subject list','Delete Experiment Error')
                        return
                end
            else
                errordlg('The delete process is canceled','Delete Experiment Error')
                return
            end
            % Update Tables
            app.Selection_and_Regenerate_SubjTable(app.selected_subjID);
            app.Selection_and_Regenerate_ExpTable(app.selected_expID);
        end

        % Button pushed function: EditExpButton
        function EditExpButtonFun(app, event)
            % Check whether select exp
            if isempty(app.selected_expID)
                errordlg('Please select one experiment','Edit Experiment Error')
                return
            end
            % Edit exp
            EditExpWindow(app)
        end

        % Button pushed function: OpenExpDataFolderButton
        function OpenExpDataFolderButtonFun(app, event)
            % Check whether select exp
            if isempty(app.selected_expID)
                errordlg('Please select one experiment','Open Experiment Error')
                return
            end
            % Open folder
            SubjInd=app.SubjSystem.searchSubj(app.selected_subjID);
            expInd=app.SubjSystem.SubjList(SubjInd).searchExp(app.selected_expID);
            ExpInfo=app.SubjSystem.SubjList(SubjInd).ExpList(expInd);
            DataPath=ExpInfo.DataPath;
            if exist(DataPath)
                winopen(DataPath)
            else
                errordlg('Datafolder cannot be found. Please check it.','Open Experiment Error')
            end
        end

        % Menu selected function: ConfigurationMenu
        function DataManagConfigFun(app, event)
            DataSystemConfigure(app)
        end

        % Callback function: ConnecttoDeviceButton, 
        % ConnecttoDeviceMenu
        function ConnectToSourceFun(app, event)
            ConnectToSourceWindow(app)
        end

        % Button pushed function: OpenSignalRecordingButton
        function OpenSignalRecordFun(app, event)
            if isempty(app.inletstreaminfo)
                warndlg('Please connect to one signal source.')
                return
            end
            if isempty(app.selected_subjID) || isempty(app.selected_expID)
                warndlg('Please select one experiment.')
                return
            end
            RecordingWindow(app);
        end

        % Button pushed function: DesignFeedbackInterfaceButton
        function DesignFeedbackInterfaceButtonFun(app, event)
            if isempty(app.selected_subjID) || isempty(app.selected_expID)
                warndlg('Please select one experiment.')
                return
            end
            FeedbackInterfaceDesign(app)
        end

        % Button pushed function: OpenFeedbackInterfaceButton
        function OpenFeedbackInterfaceFun(app, event)
            if isempty(app.selected_subjID) || isempty(app.selected_expID)
                warndlg('Please select one experiment.')
                return
            end
            if isempty(app.inletstreaminfo)
                warndlg('Please connect to one signal source.')
                return
            end
            SubjInd=app.SubjSystem.searchSubj(app.selected_subjID);
            expInd=app.SubjSystem.SubjList(SubjInd).searchExp(app.selected_expID);
            ExpInfo=app.SubjSystem.SubjList(SubjInd).ExpList(expInd);
            ExpDataPath=ExpInfo.DataPath;
            SignalStreamID=app.inletstreaminfo.source_id;
            appPath=fileparts(mfilename('fullpath'));
            system(['matlab -nodesktop -nosplash -r "cd(''' appPath ''');NFInterfaceDisp(''' ExpDataPath ''',''' SignalStreamID ''');" &']);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.DataManagementSystemMenu = uimenu(app.UIFigure);
            app.DataManagementSystemMenu.Text = 'Data Management System';
            app.ConfigurationMenu = uimenu(app.DataManagementSystemMenu);
            app.ConfigurationMenu.MenuSelectedFcn = createCallbackFcn(app, @DataManagConfigFun, true);
            app.ConfigurationMenu.Text = 'Configuration';
            app.RebuildDatabaseMenu = uimenu(app.DataManagementSystemMenu);
            app.RebuildDatabaseMenu.Text = 'Rebuild Database';
            app.DevicesMenu = uimenu(app.UIFigure);
            app.DevicesMenu.Text = 'Devices';
            app.ConnecttoDeviceMenu = uimenu(app.DevicesMenu);
            app.ConnecttoDeviceMenu.MenuSelectedFcn = createCallbackFcn(app, @ConnectToSourceFun, true);
            app.ConnecttoDeviceMenu.Text = 'Connect to Device';
            app.SubjTable = uitable(app.UIFigure);
            app.SubjTable.ColumnName = {'ID'; 'Name'; 'Gender'; 'Hand'; 'Birthday'};
            app.SubjTable.RowName = {};
            app.SubjTable.CellSelectionCallback = createCallbackFcn(app, @SubjTableSelection, true);
            app.SelectedSubjectLabel = uilabel(app.UIFigure);
            app.SelectedSubjectLabel.Text = 'Selected Subject: ';
            app.SelectedSubjectDisp = uilabel(app.UIFigure);
            app.SelectedSubjectDisp.Text = 'None';
            app.ExpTable = uitable(app.UIFigure);
            app.ExpTable.ColumnName = {'ID'; 'Name'; 'Date'; 'Exper'};
            app.ExpTable.RowName = {};
            app.ExpTable.CellSelectionCallback = createCallbackFcn(app, @ExpTableSelection, true);
            app.SelectedExpLabel = uilabel(app.UIFigure);
            app.SelectedExpLabel.Text = 'Selected Experiment:';
            app.SelectedExpDisp = uilabel(app.UIFigure);
            app.SelectedExpDisp.Text = 'None';
            app.SubjTableRefreshButton = uibutton(app.UIFigure, 'push');
            app.SubjTableRefreshButton.ButtonPushedFcn = createCallbackFcn(app, @SubjRefreshButtonPush, true);
            app.SubjTableRefreshButton.Text = 'Refresh';
            app.ExpTableRefreshButton = uibutton(app.UIFigure, 'push');
            app.ExpTableRefreshButton.ButtonPushedFcn = createCallbackFcn(app, @ExpRefreshButtonPush, true);
            app.ExpTableRefreshButton.Text = 'Refresh';
            app.AddSubjectButton = uibutton(app.UIFigure, 'push');
            app.AddSubjectButton.ButtonPushedFcn = createCallbackFcn(app, @AddSubjButton, true);
            app.AddSubjectButton.Text = 'Add Subject';
            app.RemoveSubjectButton = uibutton(app.UIFigure, 'push');
            app.RemoveSubjectButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveSubjButtonFun, true);
            app.RemoveSubjectButton.Text = 'Remove Subject';
            app.EditSubjectButton = uibutton(app.UIFigure, 'push');
            app.EditSubjectButton.ButtonPushedFcn = createCallbackFcn(app, @EditSubjButtonFun, true);
            app.EditSubjectButton.Text = 'Edit Subject';
            app.AddExpButton = uibutton(app.UIFigure, 'push');
            app.AddExpButton.ButtonPushedFcn = createCallbackFcn(app, @AddExpButtonFun, true);
            app.AddExpButton.Text = 'Add Exp';
            app.RemoveExpButton = uibutton(app.UIFigure, 'push');
            app.RemoveExpButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveExpButtonFun, true);
            app.RemoveExpButton.Text = 'Remove Exp';
            app.EditExpButton = uibutton(app.UIFigure, 'push');
            app.EditExpButton.ButtonPushedFcn = createCallbackFcn(app, @EditExpButtonFun, true);
            app.EditExpButton.Text = 'Edit Exp';
            app.OpenSubjectDataFolderButton = uibutton(app.UIFigure, 'push');
            app.OpenSubjectDataFolderButton.ButtonPushedFcn = createCallbackFcn(app, @OpenSubjDataFolderButtonFun, true);
            app.OpenSubjectDataFolderButton.Text = 'Open Subject Data Folder';
            app.OpenExpDataFolderButton = uibutton(app.UIFigure, 'push');
            app.OpenExpDataFolderButton.ButtonPushedFcn = createCallbackFcn(app, @OpenExpDataFolderButtonFun, true);
            app.OpenExpDataFolderButton.Text = 'Open Exp Data Folder';
            app.ConnecttoDeviceButton = uibutton(app.UIFigure, 'push');
            app.ConnecttoDeviceButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectToSourceFun, true);
            app.ConnecttoDeviceButton.Text = 'Connect to Device';
            app.SelectedSourceEditFieldLabel = uilabel(app.UIFigure);
            app.SelectedSourceEditFieldLabel.Text = 'Selected Source: ';
            app.SelectedSourceEditField = uieditfield(app.UIFigure, 'text');
            app.SelectedSourceEditField.Editable = 'off';
            app.OpenSignalRecordingButton = uibutton(app.UIFigure, 'push');
            app.OpenSignalRecordingButton.ButtonPushedFcn = createCallbackFcn(app, @OpenSignalRecordFun, true);
            app.OpenSignalRecordingButton.Text = 'Open Signal Recording';
            app.OpenFeedbackInterfaceButton = uibutton(app.UIFigure, 'push');
            app.OpenFeedbackInterfaceButton.ButtonPushedFcn = createCallbackFcn(app, @OpenFeedbackInterfaceFun, true);
            app.OpenFeedbackInterfaceButton.Text = 'Open Feedback Interface';
            app.DesignFeedbackInterfaceButton = uibutton(app.UIFigure, 'push');
            app.DesignFeedbackInterfaceButton.ButtonPushedFcn = createCallbackFcn(app, @DesignFeedbackInterfaceButtonFun, true);
            app.DesignFeedbackInterfaceButton.Text = 'Design Feedback Interface';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MainWindow

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @MainWindowStartup)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end