classdef NFInterface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        UIAxes    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        ExpDataPath
        SignalInlet % need delete
        MarkerOutlet % need delete
        markerID
        
        FreshTimer % need stop and delete
        MaxXLim
        MaxYLim
        FrameCount
        
        CurrentStep
        EnableSpace
        
        CurrentStepTime
        MaxStepTime
        StartStep
        NFStep
        
        FontColor
        BackgroundColor
    end
    
    methods (Access = private)
        
        function CleanWindow(app,Color)
            hold(app.UIAxes,'off')
            fill(app.UIAxes,[0 app.MaxXLim app.MaxXLim 0],[0 0 app.MaxYLim app.MaxYLim],Color);
            hold(app.UIAxes,'on')
            text(app.UIAxes,1,5,app.markerID,...
                      'fontsize',10,...
                      'Color',app.FontColor);
        end
        
        function PlotCenterText(app,DispText,FontColor,FontSize)
            th = text(app.UIAxes,app.MaxXLim/2,app.MaxYLim/2,DispText,...
                      'fontsize',FontSize,...
                      'Color',FontColor);
            set(th,'HorizontalAlignment','center','VerticalAlignment','middle')
        end
        
        function PlotFrameNumber(app)
            app.FrameCount=[app.FrameCount cputime];
            L=30;
            if length(app.FrameCount)<L
                return
            end
            app.FrameCount=app.FrameCount(end-L+1:end);
            FrameTime=mean(diff(app.FrameCount));
            FrameNumber=round(1/FrameTime);
            text(app.UIAxes,1,app.MaxYLim-5,num2str(FrameNumber),...
                      'fontsize',10,...
                      'Color',app.FontColor);
        end
        
        function FreshFun(app,~,~)
            app.CleanWindow(app.BackgroundColor);
            app.PlotFrameNumber();
            app.CurrentStepTime=cputime;
            switch app.CurrentStep
                case 0 % Start screen
                    app.EnableSpace=1;
                    % Check start
                    if app.StartStep
                        app.MaxStepTime=app.CurrentStepTime+10;
                        app.StartStep=0;
                        app.MarkerOutlet.push_sample({'StartInterface'})
                    end
                    % Check end
                    % no end
                    % Disp
                    app.PlotCenterText('Press Space to continue',app.FontColor,20);
                case 1 % Start step
                    app.EnableSpace=0;
                    % Check start
                    if app.StartStep
                        app.MaxStepTime=app.CurrentStepTime+10;
                        app.StartStep=0;
                        app.MarkerOutlet.push_sample({'StartStepIntro'})
                    end
                    % Check end
                    if app.MaxStepTime-app.CurrentStepTime<=0
                        app.CurrentStep=2;
                        app.StartStep=1;
                        app.MarkerOutlet.push_sample({'StopStepIntro'})
                    end
                    % Disp
                    DispTime=floor(app.MaxStepTime-app.CurrentStepTime);
                    if DispTime<0
                        DispTime=0;
                    end
                    app.PlotCenterText(['Starting, ' num2str(DispTime) ' s'],app.FontColor,20);
                case 2 % continue step
                    app.EnableSpace=0;
                    % Check start
                    if app.StartStep
                        app.MaxStepTime=app.CurrentStepTime+10;
                        app.StartStep=0;
                        app.MarkerOutlet.push_sample({'StartStep'})
                    end
                    % Check end
                    if app.MaxStepTime-app.CurrentStepTime<=0
                        app.CurrentStep=3;
                        app.StartStep=1;
                        app.MarkerOutlet.push_sample({'StopStep'})
                    end
                    % Disp
                    DispTime=floor(app.MaxStepTime-app.CurrentStepTime);
                    if DispTime<0
                        DispTime=0;
                    end
                    app.PlotCenterText(['Continue, ' num2str(DispTime) ' s'],app.FontColor,20);
                case 3 % Resting screen
                    app.EnableSpace=0;
                    % Check start
                    if app.StartStep
                        app.MaxStepTime=app.CurrentStepTime+10;
                        app.StartStep=0;
                        app.MarkerOutlet.push_sample({'StartRest'})
                    end
                    % Check end
                    if app.MaxStepTime-app.CurrentStepTime<=0
                        app.CurrentStep=1;
                        app.StartStep=1;
                        app.MarkerOutlet.push_sample({'StopRest'})
                    end
                    % Disp
                    DispTime=floor(app.MaxStepTime-app.CurrentStepTime);
                    if DispTime<0
                        DispTime=0;
                    end
                    app.PlotCenterText(['Resting, ' num2str(DispTime) ' s'],app.FontColor,20);
                otherwise
                    app.UIFigureCloseRequest();
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, DataPath, SignalStream)
            lib = lsl_loadlib();
            % Create Signal Inlet
            % Create Marker Outlet
            app.markerID=char(java.util.UUID.randomUUID);
            info = lsl_streaminfo(lib,'NFInterfaceMarker','Markers',1,0,'cf_string',app.markerID);
            app.MarkerOutlet=lsl_outlet(info);
            % Create Timer
            FrameRate=30;
            FreshTime=round(1/FrameRate*1000)/1000;
            app.FreshTimer=timer('BusyMode','drop',...
                                'ExecutionMode', 'fixedRate', ...   
                                'Period', FreshTime, ...          
                                'TimerFcn', @app.FreshFun);
            % Parameters
            ScreenSize=get(0,'screensize');
            ScreenSize=ScreenSize(3:4);
            app.MaxXLim=ScreenSize(1);
            app.MaxYLim=ScreenSize(2);
            app.CurrentStep=0;
            app.EnableSpace=0;
            app.NFStep=0;
            app.FrameCount=[];
            app.BackgroundColor='w';
            app.FontColor='k';
            % Create Window
            disableDefaultInteractivity(app.UIAxes);
            app.UIFigure.Position=get(0,'screensize');
            app.UIAxes.Position=get(0,'screensize');
            app.UIAxes.XLim=[0 app.MaxXLim];
            app.UIAxes.YLim=[0 app.MaxYLim];
            app.CleanWindow(app.BackgroundColor);
            app.PlotCenterText('Waiting',app.FontColor,20);
            % start fresh screen
            app.StartStep=1;
            start(app.FreshTimer);
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            if app.EnableSpace 
                if strcmp(key,'space')
                    app.CurrentStep=app.CurrentStep+1;
                    app.StartStep=1;
                end
            end
            if strcmp(key,'escape')
                app.UIFigureCloseRequest();
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            stop(app.FreshTimer)
            delete(app.FreshTimer)
            
            app.MarkerOutlet.push_sample({'Close_Interface'})
            delete(app.MarkerOutlet)
            
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XLim = [0 1];
            app.UIAxes.YLim = [0 1];
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NFInterface(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end