classdef RecordingWindow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        SourceInformationEditFieldLabel  matlab.ui.control.Label
        SourceInformationEditField  matlab.ui.control.EditField
        RecordingButton             matlab.ui.control.StateButton
        DataPathEditFieldLabel      matlab.ui.control.Label
        DataPathEditField           matlab.ui.control.EditField
        MarkerPanel                 matlab.ui.container.Panel
        MarkerDisp                  matlab.ui.control.TextArea
        ConnecttoMarkerButton       matlab.ui.control.Button
        MarkerSourceInformationEditFieldLabel  matlab.ui.control.Label
        MarkerSourceInformationEditField  matlab.ui.control.EditField
        DataPathEditField_2         matlab.ui.control.EditField
        FileLamp                    matlab.ui.control.Lamp
        WindowLengthsSliderLabel    matlab.ui.control.Label
        WindowLengthsSlider         matlab.ui.control.Slider
        PlotChannelDropDownLabel    matlab.ui.control.Label
        PlotChannelDropDown         matlab.ui.control.DropDown
        SignalDisp                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        MainApp
        
        SignalInlet % need to delete
        SignalInletStreamInfo
        MarkerInlet % need to delete
        MarkerInletStreamInfo
        
        DataPath
        WriteDataFile % need to close
        ReadFataFile % need to clase -> may not be used
        WriteMarkerFile % need to close
        
        TimeContainer
        SignalContainer
        MarkerContainer
        
        RecordDataTimer % need to stop and delete
        PlotDataTimer % need to stop and delete -> may not be used
        
        DefaultWinLen
        SamplingFreq
    end
    
    methods (Access = private)
        
        function DispInfo(app)
            if isempty(app.SignalInletStreamInfo)
                app.SourceInformationEditField.Value='None';
            else
                app.SourceInformationEditField.Value=[app.SignalInletStreamInfo.source_id ', ' app.SignalInletStreamInfo.name ', Ch ' num2str(app.SignalInletStreamInfo.channel_count) ', ' num2str(app.SignalInletStreamInfo.nominal_srate) 'Hz'];
            end
            
%             try
%                 SelectedStream=app.SignalInlet.info(1);
%                 app.SourceInformationEditField.Value=[SelectedStream.source_id ', ' SelectedStream.name ', Ch ' num2str(SelectedStream.channel_count) ', ' num2str(SelectedStream.nominal_srate) 'Hz'];
%             catch
%                 warndlg('Source disconnected!!')
%                 delete(app.SignalInlet)
%                 app.SignalInlet=[];
%                 app.SourceInformationEditField.Value='None';
%             end
            
            app.DataPathEditField.Value=app.DataPath{1};
            app.DataPathEditField_2.Value=app.DataPath{2};
            
            if isempty(app.MarkerInletStreamInfo)
                app.MarkerSourceInformationEditField.Value='None';
            else
                app.MarkerSourceInformationEditField.Value=[app.MarkerInletStreamInfo.source_id ', ' app.MarkerInletStreamInfo.name];
            end
            
%             try
%                 SelectedStream=app.MarkerInlet.info(1);
%                 app.MarkerSourceInformationEditField.Value=[SelectedStream.source_id ', ' SelectedStream.name];
%             catch
%                 delete(app.MarkerInlet)
%                 app.MarkerInlet=[];
%                 app.MarkerSourceInformationEditField.Value='None';
%             end
            
            ChannelSelectionContent={'All'};
            for i=1:app.SignalInletStreamInfo.channel_count
                ChannelSelectionContent{i+1}=['Ch ' num2str(i)];
            end
            app.PlotChannelDropDown.Items=ChannelSelectionContent;
            if ~contains(app.PlotChannelDropDown.Value,ChannelSelectionContent)
                app.PlotChannelDropDown.Value=ChannelSelectionContent{1};
            end
        end
        
        function CleanFigure(app)
            cla(app.SignalDisp);
            app.SignalDisp.XTick=[];
            app.SignalDisp.YTick=[];
            disableDefaultInteractivity(app.SignalDisp);
        end
        
        function RecordDataTimerFun(app,~,~)
            % Receive data
            [chunk,stamps]=app.SignalInlet.pull_chunk();
            % ignore the first data
%             if app.RecordDataTimer.TasksExecuted<2
%                 chunk=[];
%                 stamps=[];
%             end
            if ~isempty(app.MarkerInlet)
                while 1
                    [marker_chunk,marker_time]=app.MarkerInlet.pull_sample(0);
                    if isempty(marker_chunk)
                        break;
                    end
                    app.MarkerContainer{end+1,1}='';
                    app.MarkerContainer(2:end+1,1)=app.MarkerContainer(1:end,1);
                    app.MarkerContainer{1,1}=[num2str(marker_time) ', ' marker_chunk{1}];
                    fprintf(app.WriteMarkerFile,'%f, %s\n',marker_time,marker_chunk{1});
                end
                if ~isempty(app.MarkerContainer)
                    app.MarkerDisp.Value=app.MarkerContainer;
                end
            end
            % Save Data
            if isempty(chunk)
                % zero data
                chunk=zeros(size(app.SignalContainer,1),1);
                keepContain=app.SignalContainer(:,size(chunk,2)+1:end);
                app.SignalContainer=[keepContain chunk];%detrend(chunk.').'];
                stamps=app.TimeContainer(end)+mean(diff(app.TimeContainer));
                keepContain=app.TimeContainer(:,size(chunk,2)+1:end);
                app.TimeContainer=[keepContain stamps];
                app.PlotFigure();
                return
            end
            for time_i=1:size(chunk,2)
                fprintf(app.WriteDataFile,'%f ',[stamps(time_i) chunk(:,time_i).']);
                fprintf(app.WriteDataFile,'\n');
            end
            % Plot Data
            keepContain=app.SignalContainer(:,size(chunk,2)+1:end);
            app.SignalContainer=[keepContain chunk];%detrend(chunk.').'];
            if size(stamps,2)>1
                real_sampling_period=mean(diff(stamps));
            else
                real_sampling_period=1/app.SamplingFreq;
            end
            if app.TimeContainer(end)<(stamps(1)-app.DefaultWinLen*real_sampling_period)
                app.TimeContainer=-(length(app.TimeContainer)*real_sampling_period):real_sampling_period:0;
                app.TimeContainer=app.TimeContainer(1:end-1)+stamps(1);
            end
            keepContain=app.TimeContainer(:,size(chunk,2)+1:end);
            app.TimeContainer=[keepContain stamps];
            app.PlotFigure();
        end
        
        function PlotFigure(app)
            WinLen=app.WindowLengthsSlider.Value;
            PlotChannel=str2num(app.PlotChannelDropDown.Value(4:end));
            if isempty(PlotChannel)
                PlotChannel=1:size(app.SignalContainer,1);
            end
            % Detrend data
            PlotData=detrend(app.SignalContainer.').';
            % Plot parameter
            ySpan=100/size(PlotData,1);
            PlotSpace=ySpan/2*0.9;
            yLocation=ySpan/2*ones(1,size(PlotData,1))+ySpan*[0:1:size(PlotData,1)-1];
            % Process Channel Data
            SignalSpan=repmat(max(abs(PlotData),[],2),1,size(PlotData,2));
            PlotData=PlotData./SignalSpan;
            PlotData(isnan(PlotData))=0;
            PlotData=PlotData.*PlotSpace...
                     +yLocation';
            % Plot
            fs_estimate=1/mean(diff(app.TimeContainer));
            PlotLen=floor(fs_estimate*WinLen);
            plot(app.SignalDisp,app.TimeContainer(1,end-PlotLen+1:end),PlotData(PlotChannel,end-PlotLen+1:end).');
            XLimDisp=[min(app.TimeContainer(1,end-PlotLen+1:end)) max(app.TimeContainer(1,end-PlotLen+1:end))];
%             disp(XLimDisp)
            app.SignalDisp.XLim=XLimDisp;
            app.SignalDisp.XTick=linspace(min(XLimDisp),max(XLimDisp),6);
            grid(app.SignalDisp,'on');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.MainApp=mainapp;
            % Initial Parameter
            app.DefaultWinLen=100;
            app.SignalInlet=[];
            app.SignalInletStreamInfo=app.MainApp.inletstreaminfo;
            app.MarkerInlet=[];
            app.MarkerInletStreamInfo=[];
            ExpInfo=app.MainApp.SubjSystem.ObtainExpInfo(app.MainApp.selected_subjID,app.MainApp.selected_expID);
            Expfolder=ExpInfo.DataPath;
            app.WriteDataFile=[];
            app.WriteMarkerFile=[];
            app.ReadFataFile=[];
            app.DataPath={fullfile(Expfolder,'Data.txt');
                          fullfile(Expfolder,'Marker.txt')};
            app.SignalContainer=[];
            app.TimeContainer=[];
            app.MarkerContainer={};
            app.RecordDataTimer=timer('BusyMode','drop',...
                                        'ExecutionMode', 'fixedRate', ...   
                                        'Period', 0.1, ...          
                                        'TimerFcn', @app.RecordDataTimerFun); 
            app.PlotDataTimer=[];
            % Display
            app.DispInfo();
            % Clean Figure
            app.CleanFigure();
        end

        % Value changed function: RecordingButton
        function RecordingButtonFun(app, event)
%             value = app.RecordingButton.Value;
            if strcmp(app.RecordDataTimer.Running, 'off')
                % Connect to stream
                try
                    app.SignalInlet=lsl_inlet(app.SignalInletStreamInfo);
                    ChNumber=app.SignalInletStreamInfo.channel_count;
                    app.SamplingFreq=app.SignalInletStreamInfo.nominal_srate;
                    app.SignalInlet.open_stream(2);
                catch
                    warndlg('Source disconnected!!')
                    delete(app.SignalInlet)
                    app.SignalInlet=[];
                    app.RecordingButton.Value=false;
                    return
                end
                if ~isempty(app.MarkerInletStreamInfo)
                    try
                        app.MarkerInlet=lsl_inlet(app.MarkerInletStreamInfo);
                        app.MarkerInlet.open_stream(2);
                    catch
                        delete(app.MarkerInlet)
                        app.MarkerInlet=[];
                    end
                else
                    delete(app.MarkerInlet)
                    app.MarkerInlet=[];
                end
                % Check data file
                CurrentClockTime=clock;
                [current_file_path,current_file_name,current_file_ext]=fileparts(app.DataPath{1});
                new_file_name=['Data_' regexprep(num2str(CurrentClockTime),' +','_')];
                app.DataPath{1}=fullfile(current_file_path,[new_file_name current_file_ext]);
                if exist(app.DataPath{1},'file')
                    delet_folder_ques=questdlg('Do you want to delete existed data file?',...
                                               'Delete Data File??',...
                                               'Yes','No','No');
                    switch delet_folder_ques
                        case 'Yes'
                            delet_folder=1;
                        otherwise
                            delet_folder=0;
                    end
                    if delet_folder==1
                        delete(app.DataPath{1});
                    else
                        while 1
                            [current_file_path,current_file_name,current_file_ext]=fileparts(app.DataPath{1});
                            new_file_name=[current_file_name '_new'];
                            app.DataPath{1}=fullfile(current_file_path,[new_file_name current_file_ext]);
                            if ~exist(app.DataPath{1},'file')
                                break;
                            end
                        end
                    end
                end
                app.DataPathEditField.Value=app.DataPath{1};
                % Check marker file
                app.MarkerDisp.Value='';
                app.MarkerContainer={};
                if ~isempty(app.MarkerInletStreamInfo)
                    [current_file_path,current_file_name,current_file_ext]=fileparts(app.DataPath{2});
                    new_file_name=['Marker_' regexprep(num2str(CurrentClockTime),' +','_')];
                    app.DataPath{2}=fullfile(current_file_path,[new_file_name current_file_ext]);
                    if exist(app.DataPath{2},'file')
                        delet_folder_ques=questdlg('Do you want to delete existed marker file?',...
                                                   'Delete Marker File??',...
                                                   'Yes','No','No');
                        switch delet_folder_ques
                            case 'Yes'
                                delet_folder=1;
                            otherwise
                                delet_folder=0;
                        end
                        if delet_folder==1
                            delete(app.DataPath{2});
                        else
                            while 1
                                [current_file_path,current_file_name,current_file_ext]=fileparts(app.DataPath{2});
                                new_file_name=[current_file_name '_new'];
                                app.DataPath{2}=fullfile(current_file_path,[new_file_name current_file_ext]);
                                if ~exist(app.DataPath{2},'file')
                                    break;
                                end
                            end
                        end
                    end
                end
                app.DataPathEditField_2.Value=app.DataPath{2};
                % Disp source information
%                 app.DispInfo()
                % Clean figure
                app.CleanFigure();
                app.SignalContainer=zeros(ChNumber,app.SamplingFreq*app.DefaultWinLen);
                app.TimeContainer=-app.DefaultWinLen:1/app.SamplingFreq:0;
                app.TimeContainer=app.TimeContainer(2:end);
                app.PlotFigure();
                % Create file and start timer
                if isempty(app.SignalInlet)
                    return
                end
                app.WriteDataFile=fopen(app.DataPath{1},'a');
                PrintContent='Time ';
                for ch_i=1:ChNumber
                    PrintContent=[PrintContent 'ch_' num2str(ch_i) ' '];
                end
                fprintf(app.WriteDataFile,'%s\n',PrintContent);
                if ~isempty(app.MarkerInletStreamInfo)
                    app.WriteMarkerFile=fopen(app.DataPath{2},'a');
                    fprintf(app.WriteMarkerFile,'Time, Marker\n');
                end
                app.FileLamp.Color='green';
                start(app.RecordDataTimer)
            else
                % stop timer
                stop(app.RecordDataTimer)
                % close stream
                app.SignalInlet.close_stream();
                delete(app.SignalInlet)
                app.SignalInlet=[];
                if ~isempty(app.MarkerInlet)
                    app.MarkerInlet.close_stream();
                    delete(app.MarkerInlet)
                    app.MarkerInlet=[];
                end
                % close file
                fclose(app.WriteDataFile);
                try
                    fclose(app.WriteMarkerFile);
                end
                app.FileLamp.Color='red';
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app.SignalInlet)
            app.SignalInlet=[];
            delete(app.MarkerInlet)
            app.MarkerInlet=[];
            
            try
                fclose(app.WriteDataFile);
                fclose(app.ReadFataFile);
            end
            app.WriteDataFile=[];
            app.ReadFataFile=[];
            try
                fclose(app.WriteMarkerFile);
            end
            app.WriteMarkerFile=[];
            
            try
                stop(app.RecordDataTimer)
                stop(app.PlotDataTimer)
            end
            delete(app.RecordDataTimer)
            delete(app.PlotDataTimer)
            app.RecordDataTimer=[];
            app.PlotDataTimer=[];
            
            app.MainApp.DispInletInfo();
            
            delete(app)
        end

        % Button pushed function: ConnecttoMarkerButton
        function ConnecttoMarkerFun(app, event)
            if strcmp(app.RecordDataTimer.Running, 'on')
                warndlg('Please stop recording first');
                return;
            end
            lib = lsl_loadlib();
            result = lsl_resolve_byprop(lib,'type','Markers');
            if isempty(result)
                warndlg('Cannot find any marker source')
                app.MarkerInletStreamInfo=[];
            else
                app.MarkerInletStreamInfo=result{1};
            end
            app.DispInfo();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.SourceInformationEditFieldLabel = uilabel(app.UIFigure);
            app.SourceInformationEditFieldLabel.Text = 'Source Information: ';
            app.SourceInformationEditField = uieditfield(app.UIFigure, 'text');
            app.SourceInformationEditField.Editable = 'off';
            app.RecordingButton = uibutton(app.UIFigure, 'state');
            app.RecordingButton.ValueChangedFcn = createCallbackFcn(app, @RecordingButtonFun, true);
            app.RecordingButton.Text = 'Recording';
            app.DataPathEditFieldLabel = uilabel(app.UIFigure);
            app.DataPathEditFieldLabel.Text = 'Data Path:';
            app.DataPathEditField = uieditfield(app.UIFigure, 'text');
            app.DataPathEditField.Editable = 'off';
            app.MarkerPanel = uipanel(app.UIFigure);
            app.MarkerPanel.Title = 'Marker';
            app.MarkerDisp = uitextarea(app.MarkerPanel);
            app.ConnecttoMarkerButton = uibutton(app.MarkerPanel, 'push');
            app.ConnecttoMarkerButton.ButtonPushedFcn = createCallbackFcn(app, @ConnecttoMarkerFun, true);
            app.ConnecttoMarkerButton.Text = 'Connect to Marker';
            app.MarkerSourceInformationEditFieldLabel = uilabel(app.MarkerPanel);
            app.MarkerSourceInformationEditFieldLabel.Text = 'Marker Source Information:';
            app.MarkerSourceInformationEditField = uieditfield(app.MarkerPanel, 'text');
            app.MarkerSourceInformationEditField.Editable = 'off';
            app.DataPathEditField_2 = uieditfield(app.UIFigure, 'text');
            app.DataPathEditField_2.Editable = 'off';
            app.FileLamp = uilamp(app.UIFigure);
            app.WindowLengthsSliderLabel = uilabel(app.UIFigure);
            app.WindowLengthsSliderLabel.Text = 'Window Length (s)';
            app.WindowLengthsSlider = uislider(app.UIFigure);
            app.WindowLengthsSlider.Limits = [10 50];
            app.WindowLengthsSlider.Value = 30;
            app.PlotChannelDropDownLabel = uilabel(app.UIFigure);
            app.PlotChannelDropDownLabel.Text = 'Plot Channel:';
            app.PlotChannelDropDown = uidropdown(app.UIFigure);
            app.PlotChannelDropDown.Items = {'All'};
            app.PlotChannelDropDown.Value = 'All';
            app.SignalDisp = uiaxes(app.UIFigure);
            app.SignalDisp.XTick = [];
            app.SignalDisp.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RecordingWindow(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end