classdef SimulationSourceGenerator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        SourceTypeDropDownLabel       matlab.ui.control.Label
        SourceTypeDropDown            matlab.ui.control.DropDown
        SourceSamplingFrequencyHzEditFieldLabel  matlab.ui.control.Label
        SourceSamplingFrequencyEditField  matlab.ui.control.NumericEditField
        SourceInfoEditFieldLabel      matlab.ui.control.Label
        SourceInfoEditField           matlab.ui.control.EditField
        SourceConditionTextAreaLabel  matlab.ui.control.Label
        SourceConditionTextArea       matlab.ui.control.TextArea
        ChannelNumberEditFieldLabel   matlab.ui.control.Label
        ChannelNumberEditField        matlab.ui.control.NumericEditField
        TimerStateLamp                matlab.ui.control.Lamp
        TimerStateLabel               matlab.ui.control.Label
        StartButton                   matlab.ui.control.Button
        StopButton                    matlab.ui.control.Button
    end

    
    properties (Access = private)
        lsllib
        streamInfo
        outlet
        
        SourceType
        ChannelNumber
        SourceFreq
        SourceConditionDefaultInfo={'1. Select one source',...
                                    '2. Input an integer channel number (1-30)',...
                                    '3. Input an integer source sampling frequency (100Hz - 250Hz)',...
                                    '4. Start'};
        SineValues_10
        
        PackageNum
        SenderTimer
        
%         CheckTimer
    end
    
    methods (Access = private)
        
        function ObtainAll(app)
            app.SourceType=app.SourceTypeDropDown.Value;
            app.ChannelNumber=app.ChannelNumberEditField.Value;
            app.SourceFreq=app.SourceSamplingFrequencyEditField.Value;
        end
        
        function CheckTimerFun(app,~,~)
            if strcmp(app.SenderTimer.Running,'on')
                app.TimerStateLamp.Color='green';
                app.TimerStateLabel.Text=['Last Timer Period: ' num2str(app.SenderTimer.InstantPeriod) ', Averaged Time Period: ' num2str(app.SenderTimer.AveragePeriod)];
            else
                app.TimerStateLamp.Color='red';
                app.TimerStateLabel.Text='Timer Stop';
            end
        end
        
        function SenderTimerStartFun(app,~,~)
            % Update Timer State
            app.TimerStateLamp.Color='green';
            app.TimerStateLabel.Text='Sender Starting';
            % Get app data
            app.ObtainAll();
            sine_t=0:1/app.SourceFreq:1;
            sine_t=sine_t(1:end-1);
            app.SineValues_10=sin(2*pi*10.*sine_t);
            % Init Outlet
            app.PackageNum=0;
            SourceID=char(java.util.UUID.randomUUID);
            app.streamInfo=lsl_streaminfo(app.lsllib,...
                                          ['Simulation Source -- ' app.SourceType],...
                                          'EEG',...
                                          app.ChannelNumber,...
                                          app.SourceFreq,...
                                          'cf_float32',...
                                          SourceID);
            app.outlet=lsl_outlet(app.streamInfo);
            % Update outlet info
            app.SourceInfoEditField.Value=[app.streamInfo.source_id ', ',...
                                           app.streamInfo.name ', ',...
                                           app.streamInfo.type ', ',...
                                           'Ch ' num2str(app.streamInfo.channel_count)];
            app.SourceConditionTextArea.Value={'Sender Starting'};
        end
        
        function SenderTimerStopFun(app,~,~)
            % Update Timer State
            app.TimerStateLamp.Color='red';
            app.TimerStateLabel.Text='Timer Stop';
            % Delete outlet
            app.PackageNum=0;
            delete(app.streamInfo)
            app.streamInfo=[];
            delete(app.outlet)
            app.outlet=[];
            % Update outlet info
            app.SourceInfoEditField.Value='None';
            app.SourceConditionTextArea.Value=app.SourceConditionDefaultInfo;
        end
        
        function SenderTimerFun(app,~,~)
            % Sending Data
            switch app.SourceType
                case 'Random'
                    SendValue=randn(app.ChannelNumber,1);
                case 'Sine (10Hz)'
                    SendValue=app.SineValues_10(1);
                    app.SineValues_10=[app.SineValues_10(2:end) app.SineValues_10(1)];
                    SendValue=repmat(SendValue,app.ChannelNumber,1);
            end
            if app.outlet.have_consumers()
                app.outlet.push_sample(SendValue)
                % Update outlet info
                app.PackageNum=app.PackageNum+1;
                app.SourceConditionTextArea.Value=[{['Sending Package ' num2str(app.PackageNum)]},...
                                                   app.SourceConditionTextArea.Value(:)'];
                if length(app.SourceConditionTextArea.Value)>10
                    app.SourceConditionTextArea.Value=app.SourceConditionTextArea.Value(1:10);
                end
            else
                % Update outlet info
                app.SourceConditionTextArea.Value=[{['Waiting Lisener ' num2str(app.SenderTimer.TasksExecuted)]},...
                                                   app.SourceConditionTextArea.Value(:)'];
                if length(app.SourceConditionTextArea.Value)>10
                    app.SourceConditionTextArea.Value=app.SourceConditionTextArea.Value(1:10);
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.SourceConditionTextArea.Value=app.SourceConditionDefaultInfo;
            app.TimerStateLamp.Color='red';
            app.TimerStateLabel.Text='Timer Stop';
            
            app.ObtainAll();
            
            app.SineValues_10=[];
            
            app.lsllib=lsl_loadlib();
            app.streamInfo=[];
            app.outlet=[];
            app.PackageNum=0;
            app.SenderTimer = timer(...
                'BusyMode','queue',...
                'ExecutionMode', 'fixedRate', ...   
                'Period', 1/app.SourceFreq, ...          
                'TimerFcn', @app.SenderTimerFun,...
                'StartFcn', @app.SenderTimerStartFun,...
                'StopFcn',  @app.SenderTimerStopFun);  
%             app.CheckTimer = timer(...
%                 'BusyMode','queue',... %'drop'
%                 'ExecutionMode', 'fixedSpacing', ...   
%                 'Period', 60, ...          
%                 'TimerFcn', @app.CheckTimerFun);  
        end

        % Value changed function: ChannelNumberEditField
        function ChannelNumberCahngeFun(app, event)
            value = app.ChannelNumberEditField.Value;
            if value>=1 && value<=30
                value=floor(value);
                app.ChannelNumberEditField.Value=value;
            end
        end

        % Value changed function: SourceSamplingFrequencyEditField
        function SourceSamplingFreqChangeFun(app, event)
            value = app.SourceSamplingFrequencyEditField.Value;
            if value>=100 && value<=250
                value=floor(value);
                app.SourceSamplingFrequencyEditField.Value=value;
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            stop(app.SenderTimer)
            delete(app.SenderTimer)
%             stop(app.CheckTimer)
%             delete(app.CheckTimer)
            delete(app.outlet)
            app.outlet=[];
            delete(app)
        end

        % Button pushed function: StartButton
        function StartButtonFun(app, event)
            if strcmp(app.SenderTimer.Running, 'off')% && strcmp(app.CheckTimer.Running,'off')
                app.ObtainAll();
                stop(app.SenderTimer)
                delete(app.SenderTimer)
                app.SenderTimer = timer('BusyMode','drop',...
                                        'ExecutionMode', 'fixedRate', ...   
                                        'Period', 1/app.SourceFreq, ...          
                                        'TimerFcn', @app.SenderTimerFun,...
                                        'StartFcn', @app.SenderTimerStartFun,...
                                        'StopFcn',  @app.SenderTimerStopFun); 
                start(app.SenderTimer);
%                 start(app.CheckTimer);
            else
                warndlg('Sender is already running!!')
            end
        end

        % Button pushed function: StopButton
        function StopButtonFun(app, event)
            stop(app.SenderTimer);
%             stop(app.CheckTimer)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.SourceTypeDropDownLabel = uilabel(app.UIFigure);
            app.SourceTypeDropDownLabel.Text = 'Source Type: ';
            app.SourceTypeDropDown = uidropdown(app.UIFigure);
            app.SourceTypeDropDown.Items = {'Random', 'Sine (10Hz)'};
            app.SourceTypeDropDown.Value = 'Sine (10Hz)';
            app.SourceSamplingFrequencyHzEditFieldLabel = uilabel(app.UIFigure);
            app.SourceSamplingFrequencyHzEditFieldLabel.Text = 'Source Sampling Frequency (Hz): ';
            app.SourceSamplingFrequencyEditField = uieditfield(app.UIFigure, 'numeric');
            app.SourceSamplingFrequencyEditField.Limits = [100 250];
            app.SourceSamplingFrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @SourceSamplingFreqChangeFun, true);
            app.SourceSamplingFrequencyEditField.Value = 100;
            app.SourceInfoEditFieldLabel = uilabel(app.UIFigure);
            app.SourceInfoEditFieldLabel.Text = 'Source Info: ';
            app.SourceInfoEditField = uieditfield(app.UIFigure, 'text');
            app.SourceInfoEditField.Editable = 'off';
            app.SourceInfoEditField.Value = 'None';
            app.SourceConditionTextAreaLabel = uilabel(app.UIFigure);
            app.SourceConditionTextAreaLabel.Text = 'Source Condition';
            app.SourceConditionTextArea = uitextarea(app.UIFigure);
            app.SourceConditionTextArea.Editable = 'off';
            app.ChannelNumberEditFieldLabel = uilabel(app.UIFigure);
            app.ChannelNumberEditFieldLabel.Text = 'Channel Number:';
            app.ChannelNumberEditField = uieditfield(app.UIFigure, 'numeric');
            app.ChannelNumberEditField.Limits = [1 8];
            app.ChannelNumberEditField.ValueChangedFcn = createCallbackFcn(app, @ChannelNumberCahngeFun, true);
            app.ChannelNumberEditField.Value = 8;
            app.TimerStateLamp = uilamp(app.UIFigure);
            app.TimerStateLabel = uilabel(app.UIFigure);
            app.TimerStateLabel.Text = 'Timer State';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonFun, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonFun, true);
            app.StopButton.Text = 'Stop';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SimulationSourceGenerator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end